classdef main_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AppLogoMenu                  matlab.ui.container.Menu
        AircraftMenu                 matlab.ui.container.Menu
        LoadExistingDataMenu         matlab.ui.container.Menu
        SimulationMenu               matlab.ui.container.Menu
        ImportExistingDataMATMenu    matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        AircraftDataTab              matlab.ui.container.Tab
        MassLabel                    matlab.ui.control.Label
        MassEditField                matlab.ui.control.NumericEditField
        AircraftEditFieldLabel       matlab.ui.control.Label
        AircraftEditField            matlab.ui.control.EditField
        CargoMassEditFieldLabel      matlab.ui.control.Label
        CargoMassEditField           matlab.ui.control.NumericEditField
        FuelEditFieldLabel           matlab.ui.control.Label
        FuelEditField                matlab.ui.control.NumericEditField
        InertiasKgm2Label            matlab.ui.control.Label
        IxxEditFieldLabel            matlab.ui.control.Label
        IxxEditField                 matlab.ui.control.NumericEditField
        IyyEditFieldLabel            matlab.ui.control.Label
        IyyEditField                 matlab.ui.control.NumericEditField
        IzzEditFieldLabel            matlab.ui.control.Label
        IzzEditField                 matlab.ui.control.NumericEditField
        IzxEditFieldLabel            matlab.ui.control.Label
        IzxEditField                 matlab.ui.control.NumericEditField
        IyzEditFieldLabel            matlab.ui.control.Label
        IyzEditField                 matlab.ui.control.NumericEditField
        IxyEditFieldLabel            matlab.ui.control.Label
        IxyEditField                 matlab.ui.control.NumericEditField
        SEditFieldLabel              matlab.ui.control.Label
        SEditField                   matlab.ui.control.NumericEditField
        cEditFieldLabel              matlab.ui.control.Label
        cEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        ShEditFieldLabel             matlab.ui.control.Label
        ShEditField                  matlab.ui.control.NumericEditField
        SvEditFieldLabel             matlab.ui.control.Label
        SvEditField                  matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        CLButton                     matlab.ui.control.Button
        CoefficentsPlotLabel         matlab.ui.control.Label
        CD0Button                    matlab.ui.control.Button
        CD_Button                    matlab.ui.control.Button
        CD_MachButton                matlab.ui.control.Button
        Cm_eButton_2                 matlab.ui.control.Button
        Cl_aButton                   matlab.ui.control.Button
        EngineEditFieldLabel         matlab.ui.control.Label
        EngineEditField              matlab.ui.control.EditField
        EngineNumbEditField          matlab.ui.control.NumericEditField
        kCLgeButton                  matlab.ui.control.Button
        kCDgeButton                  matlab.ui.control.Button
        UCALogo                      matlab.ui.control.Image
        OtherCoefficientsPanel       matlab.ui.container.Panel
        dCLflapLabel                 matlab.ui.control.Label
        dCLflapEditField             matlab.ui.control.NumericEditField
        CL_eLabel                    matlab.ui.control.Label
        CL_eEditField                matlab.ui.control.NumericEditField
        CDiEditFieldLabel            matlab.ui.control.Label
        CDiEditField                 matlab.ui.control.NumericEditField
        CDflapEditFieldLabel         matlab.ui.control.Label
        CDflapEditField              matlab.ui.control.NumericEditField
        CDgearEditFieldLabel         matlab.ui.control.Label
        CDgearEditField              matlab.ui.control.NumericEditField
        CDsbEditFieldLabel           matlab.ui.control.Label
        CDsbEditField                matlab.ui.control.NumericEditField
        CDspEditFieldLabel           matlab.ui.control.Label
        CDspEditField                matlab.ui.control.NumericEditField
        CDeLabel                     matlab.ui.control.Label
        CDeEditField                 matlab.ui.control.NumericEditField
        CYLabel                      matlab.ui.control.Label
        CYEditField                  matlab.ui.control.NumericEditField
        Cm0EditFieldLabel            matlab.ui.control.Label
        Cm0EditField                 matlab.ui.control.NumericEditField
        CmLabel                      matlab.ui.control.Label
        CmEditField                  matlab.ui.control.NumericEditField
        CmLabel_2                    matlab.ui.control.Label
        CmEditField_2                matlab.ui.control.NumericEditField
        CmqEditFieldLabel            matlab.ui.control.Label
        CmqEditField                 matlab.ui.control.NumericEditField
        CnLabel                      matlab.ui.control.Label
        CnEditField                  matlab.ui.control.NumericEditField
        CndrEditFieldLabel           matlab.ui.control.Label
        CndrEditField                matlab.ui.control.NumericEditField
        CnrEditFieldLabel            matlab.ui.control.Label
        CnrEditField                 matlab.ui.control.NumericEditField
        ClrEditFieldLabel            matlab.ui.control.Label
        ClrEditField                 matlab.ui.control.NumericEditField
        ClpEditFieldLabel            matlab.ui.control.Label
        ClpEditField                 matlab.ui.control.NumericEditField
        ClEditFieldLabel             matlab.ui.control.Label
        ClEditField                  matlab.ui.control.NumericEditField
        CldrEditFieldLabel           matlab.ui.control.Label
        CldrEditField                matlab.ui.control.NumericEditField
        dLabel                       matlab.ui.control.Label
        dEditField                   matlab.ui.control.NumericEditField
        kCLsbEditFieldLabel          matlab.ui.control.Label
        kCLsbEditField               matlab.ui.control.NumericEditField
        kCLspEditFieldLabel          matlab.ui.control.Label
        kCLspEditField               matlab.ui.control.NumericEditField
        cLabel                       matlab.ui.control.Label
        cEditField_2                 matlab.ui.control.NumericEditField
        RealTimeTab                  matlab.ui.container.Tab
        JoyStickConecctionLampLabel  matlab.ui.control.Label
        JoyStickConecctionLamp       matlab.ui.control.Lamp
        OpenSimulinkModelButton      matlab.ui.control.Button
        WarningLabel                 matlab.ui.control.Label
        DonotopenmodelifjoystickconnectionisredLabel  matlab.ui.control.Label
        UCALogo_2                    matlab.ui.control.Image
        InitialConditionsPanel       matlab.ui.container.Panel
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        zEditFieldLabel              matlab.ui.control.Label
        zEditField                   matlab.ui.control.NumericEditField
        uEditFieldLabel              matlab.ui.control.Label
        uEditField                   matlab.ui.control.NumericEditField
        vEditFieldLabel              matlab.ui.control.Label
        vEditField                   matlab.ui.control.NumericEditField
        wEditFieldLabel              matlab.ui.control.Label
        wEditField                   matlab.ui.control.NumericEditField
        pEditFieldLabel              matlab.ui.control.Label
        pEditField                   matlab.ui.control.NumericEditField
        qEditFieldLabel              matlab.ui.control.Label
        qEditField                   matlab.ui.control.NumericEditField
        rEditFieldLabel              matlab.ui.control.Label
        rEditField                   matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        PsiEditField                 matlab.ui.control.NumericEditField
        EditField_2Label             matlab.ui.control.Label
        ThetaEditField               matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        PhiEditField                 matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel       matlab.ui.control.Label
        LatitudeEditField            matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel      matlab.ui.control.Label
        LongitudeEditField           matlab.ui.control.NumericEditField
        ElevationEditFieldLabel      matlab.ui.control.Label
        ElevationEditField           matlab.ui.control.NumericEditField
        ActuatorTrimPanel            matlab.ui.container.Panel
        AileronEditFieldLabel        matlab.ui.control.Label
        AileronEditField             matlab.ui.control.NumericEditField
        RudderEditFieldLabel         matlab.ui.control.Label
        RudderEditField              matlab.ui.control.NumericEditField
        ElevatorEditFieldLabel       matlab.ui.control.Label
        ElevatorEditField            matlab.ui.control.NumericEditField
        EngineEditField_2Label       matlab.ui.control.Label
        EngineEditField_2            matlab.ui.control.NumericEditField
        DateDatePickerLabel          matlab.ui.control.Label
        DateDatePicker               matlab.ui.control.DatePicker
        DayTimeLabel                 matlab.ui.control.Label
        DayTimeSpinner               matlab.ui.control.Spinner
        TimeStepLabel                matlab.ui.control.Label
        TimeStepSpinner              matlab.ui.control.Spinner
        AdjustTrimConditionsButton   matlab.ui.control.Button
        ApIndexLabel                 matlab.ui.control.Label
        ApDropDown                   matlab.ui.control.DropDown
        MissionTab                   matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        AddRowButton                 matlab.ui.control.Button
        DeleteRowButton              matlab.ui.control.Button
        RunMissionButton             matlab.ui.control.Button
        WarningLabel_2               matlab.ui.control.Label
        EachoperationhavedifferentpropertiesdonotconfusethemLabel  matlab.ui.control.Label
        TakeOffLabel                 matlab.ui.control.Label
        NoDataneededLabel            matlab.ui.control.Label
        CruiseLabel                  matlab.ui.control.Label
        Data1HeightinftData2VelocityinknotsData3DistanceinKmLabel  matlab.ui.control.Label
        DesignedMissionExecutionButtonGroup  matlab.ui.container.ButtonGroup
        BackgroundExecutionButton    matlab.ui.control.RadioButton
        ExecutiononModelButton       matlab.ui.control.RadioButton
        ContinueinRealLabel          matlab.ui.control.Label
        NoDataneededDonotchoosethisoptioninBackgroundExecutionLabel  matlab.ui.control.Label
        MissionCheckBox              matlab.ui.control.CheckBox
        ResultsTab                   matlab.ui.container.Tab
        ShowResultsButton            matlab.ui.control.Button
        MapTypeDropDownLabel         matlab.ui.control.Label
        MapTypeDropDown              matlab.ui.control.DropDown
        ZoomEditFieldLabel           matlab.ui.control.Label
        ZoomEditField                matlab.ui.control.NumericEditField
        GeographicTrackMapCheckBox   matlab.ui.control.CheckBox
        DPositionCheckBox            matlab.ui.control.CheckBox
        HeightVsCheckBox             matlab.ui.control.CheckBox
        FuelVsCheckBox               matlab.ui.control.CheckBox
        VsDropDown                   matlab.ui.control.DropDown
        VsDropDown_2                 matlab.ui.control.DropDown
        ExportResultsButton          matlab.ui.control.Button
        ExportType                   matlab.ui.control.DropDown
        toLabel                      matlab.ui.control.Label
        FilenameEditFieldLabel       matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        DateIndexCheckBox            matlab.ui.control.CheckBox
        Copyright2022ngelDanielVelzquezRodrguezLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CurrentFolder = fileparts(mfilename('fullpath')); % App Path
        VarsNames = NaN;
        main_Menu = NaN;
        JoyStick_Connection = NaN;
        TableData = NaN;
%         result_Menu = NaN;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.UIFigure.Name = 'TGF 6DOF Console by Ángel Daniel VR';
            
            cd(app.CurrentFolder + "\Misc")
            I = imread('Simulator Logo 1.png');
            figure('Name','TGF 6DOF Consolose by Ángel Daniel VR','NumberTitle','off','MenuBar','none','ToolBar','none','Color','w','WindowState','fullscreen')
            imshow(I)
            cd(app.CurrentFolder)
            clear I
            pause(1.7)
            evalin('base','close all')
            
            % Workspace Clear
            evalin('base', 'clear')
            evalin('base', 'close all')
            
            % Path Directory
            cd(app.CurrentFolder);
            
            % Visual Images
            app.UCALogo.ImageSource = app.CurrentFolder + "\Misc\UCA_Black_Logo.png";
            app.UCALogo_2.ImageSource = app.CurrentFolder + "\Misc\UCA_Black_Logo.png";
            
            % Mision Table
            app.UITable.ColumnFormat = {{'Take Off','Cruise','Continue in Real'},'numeric','numeric','numeric','numeric'};
            app.UITable.Data = cell(1,5);
            
            % Initial Conditions
            app.main_Menu = 3;
            cd(app.CurrentFolder);
            main_Menu = app.main_Menu;
            run(app.CurrentFolder + "\main.m")
            for i = 1:length(VarsNames)
                assignin('base',char(VarsNames(i)),eval(char(VarsNames(i))));
            end
            app.xEditField.Value = x_ini;
            app.yEditField.Value = y_ini;
            app.zEditField.Value = z_ini;
            app.uEditField.Value = u_ini;
            app.vEditField.Value = v_ini;
            app.wEditField.Value = w_ini;
            app.pEditField.Value = p_ini*180/pi;
            app.qEditField.Value = q_ini*180/pi;
            app.rEditField.Value = r_ini*180/pi;
            app.PhiEditField.Value = phi_ini*180/pi;
            app.ThetaEditField.Value = theta_ini*180/pi;
            app.PsiEditField.Value = psi_ini*180/pi+90;
            
            app.AileronEditField.Value = Aileron_Trim*100;
            app.RudderEditField.Value = Rudder_Trim*100;
            app.ElevatorEditField.Value = Elevator_Trim*100;
            app.EngineEditField_2.Value = Engine_Trim*100;
            
            app.TimeStepSpinner.Value = time_step;
            
            %  GeoTime Conditions
            app.main_Menu = 4;
            cd(app.CurrentFolder);
            main_Menu = app.main_Menu;
            run(app.CurrentFolder + "\main.m")
            for i = 1:length(VarsNames)
                assignin('base',char(VarsNames(i)),eval(char(VarsNames(i))));
            end
            
            app.DateDatePicker.Value = today("datetime");
            app.DayTimeSpinner.Value = GeoDate_Time/3600;
            app.LatitudeEditField.Value = dms2degrees(Lat);
            app.LongitudeEditField.Value = dms2degrees(Long);
            app.ElevationEditField.Value = Elv;
            
            app.ApDropDown.Value = char(string(GeoDate_Ap));
            
            % Joystick Verification
            app.JoyStickConecctionLamp.Color = 'red';
            app.main_Menu = 6;
            cd(app.CurrentFolder);
            main_Menu = app.main_Menu;
            run(app.CurrentFolder + "\main.m")
            app.JoyStick_Connection = JoyStick_Connection;
            if JoyStick_Connection ~= pi
                app.JoyStickConecctionLamp.Color = 'green';
            end
            
            evalin('base', 'clc')
            
            %clear all, close all, clc
            %app.CurrentFolder = fileparts(mfilename('fullpath'));
            %app.xEditFieldLabel.Interpreter = 'latex';
            %app.xEditFieldLabel.Text = '$\psi$';
        end

        % Callback function
        function ButtonPushed(app, event)
% %             run(app.CurrentFolder + "\prueba.m")
%             cd(app.CurrentFolder);
%             run(app.CurrentFolder + "\main2.m")
%             
%             app.PsiEditField.Value = psi_ini*180/pi+90;
% %             assignin('base','VarsNames',VarsNames);
%             
%             for i = 1:length(VarsNames)
%                 assignin('base',char(VarsNames(i)),eval(char(VarsNames(i))));
%             end
%             app.VarsNames = VarsNames;
%             
            
        end

        % Menu selected function: LoadExistingDataMenu
        function AircraftData(app, event)
            app.main_Menu = 1;
            cd(app.CurrentFolder);
            
            main_Menu = app.main_Menu;
            run(app.CurrentFolder + "\main.m")
            
            for i = 1:length(VarsNames)
                assignin('base',char(VarsNames(i)),eval(char(VarsNames(i))));
            end
            app.VarsNames = VarsNames;
            
            app.AircraftEditField.Value = Aircraft_Name;
            app.EngineEditField.Value = Engine_Name;
            app.EngineNumbEditField.Value = NMotores;
            app.MassEditField.Value = mass0;
            app.CargoMassEditField.Value = cargo_mass;
            app.FuelEditField.Value = fuel;
            
            app.IxxEditField.Value = Ixx;
            app.IyyEditField.Value = Iyy;
            app.IzzEditField.Value = Izz;
            app.IxyEditField.Value = Ixy;
            app.IyzEditField.Value = Iyz;
            app.IzxEditField.Value = Ixz;
            
            app.SEditField.Value = S;
            app.cEditField.Value = c;
            app.bEditField.Value = b;
            app.ShEditField.Value = Sh;
            app.SvEditField.Value = Sv;
            
            xq1 = min(CL_alpha_reg(:,1))-(5/180*pi):0.01:max(CL_alpha_reg(:,1))+(0/180*pi);
            yq1 = polyval(CL_alpha_interp,xq1);
            plot(app.UIAxes,xq1*180/pi,yq1,'-b')
            title(app.UIAxes, 'Lift Coefficient')
            xlabel(app.UIAxes, 'α')
            ylabel(app.UIAxes, 'CL')
            clear xq1 yq1
            
            app.dCLflapEditField.Value = dCLflap;
            app.CL_eEditField.Value = CLde;
            app.CDiEditField.Value = CDi;
            app.CDflapEditField.Value = CDflap;
            app.CDgearEditField.Value = CDgear;
            app.CDsbEditField.Value = CDsb;
            app.CDspEditField.Value = CDsp;
            app.CDeEditField.Value = CDde;
            app.CYEditField.Value = CYb;
            app.Cm0EditField.Value = Cm0;
            app.CmEditField.Value = dCLflap;
            app.dCLflapEditField.Value = Cm_alpha;
            app.CmEditField_2.Value = Cmadot;
            app.CmqEditField.Value = Cmq;
            app.CnEditField.Value = Cnb;
            app.CndrEditField.Value = Cndr;
            app.CnrEditField.Value = Cnr;
            app.ClrEditField.Value = Clr;
            app.ClpEditField.Value = Clp;
            app.ClEditField.Value = Clb;
            app.CldrEditField.Value = Cldr;
            app.dEditField.Value = mu_d;
            app.cEditField_2.Value = mu_c;
            app.kCLsbEditField.Value = kCLsb;
            app.kCLspEditField.Value = kCLsp;
            
            evalin('base', 'clc')
        end

        % Button pushed function: OpenSimulinkModelButton
        function OpenModel(app, event)
            cd(app.CurrentFolder);
            
            evalin('base', 'who');
            
            open_system('SimulatorModel.mdl')
        end

        % Button pushed function: CLButton
        function CL_Plot(app, event)
            cla(app.UIAxes,"reset")
            CL_alpha_reg = evalin('base','CL_alpha_reg');
            CL_alpha_interp = evalin('base','CL_alpha_interp');
            
            xq1 = min(CL_alpha_reg(:,1))-(5/180*pi):0.01:max(CL_alpha_reg(:,1))+(0/180*pi);
            yq1 = polyval(CL_alpha_interp,xq1);
            plot(app.UIAxes,xq1*180/pi,yq1,'-b')
            title(app.UIAxes, 'Lift Coefficient')
            xlabel(app.UIAxes, 'α')
            ylabel(app.UIAxes, 'CL')
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            clear xq1 yq1
        end

        % Button pushed function: CD0Button
        function CD0_Plot(app, event)
            cla(app.UIAxes,"reset")
            CD0_reg = evalin('base','CD0_reg');
            CD0_interp = evalin('base','CD0_interp');
            
            xq1 = min(CD0_reg(:,1))-(5/180*pi):0.01:max(CD0_reg(:,1))+(5/180*pi);
            yq1 = polyval(CD0_interp,xq1);
            plot(app.UIAxes,xq1*180/pi,yq1,'-b')
            title(app.UIAxes, 'Zero-Lift Drag Coefficient')
            xlabel(app.UIAxes, 'α')
            ylabel(app.UIAxes, 'CD')
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            clear xq1 yq1
        end

        % Button pushed function: CD_Button
        function CDbeta_Plot(app, event)
            cla(app.UIAxes,"reset")
            CD_beta_reg = evalin('base','CD_beta_reg');
            CD_beta_interp = evalin('base','CD_beta_interp');
            
            xq1 = min(CD_beta_reg(:,1))-(5/180*pi):0.01:max(CD_beta_reg(:,1))+(5/180*pi);
            yq1 = polyval(CD_beta_interp,xq1);
            plot(app.UIAxes,xq1*180/pi,yq1,'-b')
            title(app.UIAxes, 'Drag due to Beta Coefficient')
            xlabel(app.UIAxes, 'β')
            ylabel(app.UIAxes, 'CD')
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            clear xq1 yq1
        end

        % Button pushed function: CD_MachButton
        function CDmach_Plot(app, event)
            cla(app.UIAxes,"reset")
            CD_mach_reg = evalin('base','CD_mach_reg');
            CD_beta_interp = evalin('base','CD_beta_interp');
            
            xq1 = min(CD_mach_reg(:,1)):0.01:max(CD_mach_reg(:,1));
            yq1 = interp1(CD_mach_reg(:,1),CD_mach_reg(:,2),xq1,'pchip');
            plot(app.UIAxes,xq1,yq1,'-b')
            title(app.UIAxes, 'Drag due to Mach Coefficient')
            xlabel(app.UIAxes, 'Mach')
            ylabel(app.UIAxes, 'CD')
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            clear xq1 yq1
        end

        % Button pushed function: Cm_eButton_2
        function Cmde_Plot(app, event)
            cla(app.UIAxes,"reset")
            Cmde_reg = evalin('base','Cmde_reg');
            Cmde_interp = evalin('base','Cmde_interp');
            
            xq1 = min(Cmde_reg(:,1)):0.01:max(Cmde_reg(:,1));
            yq1 = polyval(Cmde_interp,xq1);
            plot(app.UIAxes,xq1,yq1,'-b')
            title(app.UIAxes, 'Pitch Moment due to Elevator(Mach) Coefficient')
            xlabel(app.UIAxes, 'Mach')
            ylabel(app.UIAxes, 'Cm')
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            clear xq1 yq1
        end

        % Button pushed function: Cl_aButton
        function Clda_Plot(app, event)
            cla(app.UIAxes,"reset")
            Clda_Reg = evalin('base','Clda_Reg');
            Clda_interp = evalin('base','Clda_interp');
            
            xq1 = min(Clda_Reg(:,1)):0.01:max(Clda_Reg(:,1));
            yq1 = polyval(Clda_interp,xq1);
            plot(app.UIAxes,xq1,yq1,'-b')
            title(app.UIAxes, 'Roll Moment due to Aileron(Mach) Coefficient')
            xlabel(app.UIAxes, 'Mach')
            ylabel(app.UIAxes, 'Cl')
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            clear xq1 yq1
        end

        % Value changed function: CargoMassEditField
        function CargoMass(app, event)
            value = app.CargoMassEditField.Value;
            assignin('base','cargo_mass',value);
        end

        % Value changed function: FuelEditField
        function Fuel(app, event)
            value = app.FuelEditField.Value;
            assignin('base','fuel',value);
        end

        % Button pushed function: kCLgeButton
        function kCLge_Plot(app, event)
            cla(app.UIAxes,"reset")
            b = evalin('base','b');
            kCLge_reg = evalin('base','kCLge_reg');
            kCLge_interp = evalin('base','kCLge_interp');
            
            xq1 = (min(kCLge_reg(:,1))*b):0.01:(max(kCLge_reg(:,1))*b);
            yq1 = polyval(kCLge_interp,xq1);
            plot(app.UIAxes,xq1,yq1,'-b')
            title(app.UIAxes, 'Lift Factor due to Ground Effect')
            xlabel(app.UIAxes, 'Height')
            ylabel(app.UIAxes, 'kCL')
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            clear xq1 yq1
        end

        % Button pushed function: kCDgeButton
        function kCDge_Plot(app, event)
            cla(app.UIAxes,"reset")
            b = evalin('base','b');
            kCDge_reg = evalin('base','kCDge_reg');
            kCDge_interp = evalin('base','kCDge_interp');
            
            xq1 = (min(kCDge_reg(:,1))*b):0.01:(max(kCDge_reg(:,1))*b);
            yq1 = polyval(kCDge_interp,xq1);
            plot(app.UIAxes,xq1,yq1,'-b')
            title(app.UIAxes, 'Drag Factor due to Ground Effect')
            xlabel(app.UIAxes, 'Height')
            ylabel(app.UIAxes, 'kCD')
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            clear xq1 yq1
        end

        % Value changed function: AileronEditField
        function AileronTrim(app, event)
            value = app.AileronEditField.Value/100;
            assignin('base','Aileron_Trim',value);
        end

        % Value changed function: RudderEditField
        function RudderTrim(app, event)
            value = app.RudderEditField.Value/100;
            assignin('base','Rudder_Trim',value);
        end

        % Value changed function: ElevatorEditField
        function ElevatorTrim(app, event)
            value = app.ElevatorEditField.Value/100;
            assignin('base','Elevator_Trim',value);
        end

        % Value changed function: EngineEditField_2
        function EngineTrim(app, event)
            value = app.EngineEditField_2.Value/100;
            assignin('base','Engine_Trim',value);
        end

        % Value changed function: LatitudeEditField
        function Lat(app, event)
            value = app.LatitudeEditField.Value;
            GeoDate_LatLongElv = [value dms2degrees(evalin('base','Long')) evalin('base','Elv')];
            assignin('base','GeoDate_LatLongElv',GeoDate_LatLongElv);
        end

        % Value changed function: ApDropDown
        function ApSelect(app, event)
            value = app.ApDropDown.Value;
            GeoDate_Ap = str2num(value);
            assignin('base','GeoDate_Ap',GeoDate_Ap);
        end

        % Value changed function: DayTimeSpinner
        function DayTime(app, event)
            value = app.DayTimeSpinner.Value;
            GeoDate_Time = value*3600;
            assignin('base','GeoDate_Time',GeoDate_Time);
        end

        % Value changed function: DateDatePicker
        function DayDate(app, event)
            value = app.DateDatePicker.Value;
            GeoDate_Clock = clock;
            GeoDate_Day = datenum(char(value),'dd-mmm-yyyy')-datenum(char("01-January-" + GeoDate_Clock(1)'),'dd-mmm-yyyy');
            assignin('base','GeoDate_Day',GeoDate_Day);
        end

        % Value changed function: TimeStepSpinner
        function TimeStep(app, event)
            value = app.TimeStepSpinner.Value;
            assignin('base','time_step',value);
        end

        % Button pushed function: AdjustTrimConditionsButton
        function Trim(app, event)
%             evalin('base', 'who')
%             clear aux
            aux = evalin('base', 'who');
%             global auxx
%             for i = 1:length(aux)
%                 assignin('caller',char(aux(i)),evalin('base',char(aux(i))))    
%             end
%             clear aux
            app.main_Menu = 5;
            cd(app.CurrentFolder);
            main_Menu = app.main_Menu;
            run(app.CurrentFolder + "\main.m")
            for i = 1:length(VarsNames)
                assignin('caller',char(VarsNames(i)),eval(char(VarsNames(i))));
                assignin('base',char(VarsNames(i)),evalin('caller',char(VarsNames(i))));
            end
            clear aux i
            
            app.xEditField.Value = evalin('caller', 'x_ini');
            app.yEditField.Value = evalin('caller', 'y_ini');
            app.zEditField.Value = evalin('caller', 'z_ini');
            app.uEditField.Value = evalin('caller', 'u_ini');
            app.vEditField.Value = evalin('caller', 'v_ini');
            app.wEditField.Value = evalin('caller', 'w_ini');
            app.pEditField.Value = evalin('caller', 'p_ini')*180/pi;
            app.qEditField.Value = evalin('caller', 'q_ini')*180/pi;
            app.rEditField.Value = evalin('caller', 'r_ini')*180/pi;
            app.PhiEditField.Value = evalin('caller', 'phi_ini')*180/pi;
            app.ThetaEditField.Value = evalin('caller', 'theta_ini')*180/pi;
            app.PsiEditField.Value = evalin('caller', 'psi_ini')*180/pi+90;
            
            app.AileronEditField.Value = evalin('caller', 'Aileron_Trim')*100;
            app.RudderEditField.Value = evalin('caller', 'Rudder_Trim')*100;
            app.ElevatorEditField.Value = evalin('caller', 'Elevator_Trim')*100;
            app.EngineEditField_2.Value = evalin('caller', 'Engine_Trim')*100;
            
            evalin('base', 'clear i aux')
            evalin('caller', 'clear')
            evalin('base', 'clc')
        end

        % Value changed function: xEditField
        function x_ini(app, event)
            value = app.xEditField.Value;
            assignin('base','x_ini',value);
        end

        % Value changed function: yEditField
        function y_ini(app, event)
            value = app.yEditField.Value;
            assignin('base','y_ini',value);
        end

        % Value changed function: zEditField
        function z_ini(app, event)
            value = app.zEditField.Value;
            assignin('base','z_ini',value);
        end

        % Value changed function: uEditField
        function u_ini(app, event)
            value = app.uEditField.Value;
            assignin('base','u_ini',value);
        end

        % Value changed function: vEditField
        function v_ini(app, event)
            value = app.vEditField.Value;
            assignin('base','v_ini',value);
        end

        % Value changed function: wEditField
        function w_ini(app, event)
            value = app.wEditField.Value;
            assignin('base','w_ini',value);
        end

        % Value changed function: pEditField
        function p_ini(app, event)
            value = app.pEditField.Value/180*pi;
            assignin('base','p_ini',value);
        end

        % Value changed function: qEditField
        function q_ini(app, event)
            value = app.qEditField.Value/180*pi;
            assignin('base','q_ini',value);
        end

        % Value changed function: rEditField
        function r_ini(app, event)
            value = app.rEditField.Value/180*pi;
            assignin('base','r_ini',value);
        end

        % Value changed function: PhiEditField
        function phi_ini(app, event)
            value = app.PhiEditField.Value/180*pi;
            assignin('base','phi_ini',value);
        end

        % Value changed function: ThetaEditField
        function theta_ini(app, event)
            value = app.ThetaEditField.Value/180*pi;
            assignin('base','theta_ini',value);
        end

        % Value changed function: PsiEditField
        function psi_ini(app, event)
            value = app.PsiEditField.Value/180*pi;
            assignin('base','psi_ini',value);
        end

        % Button pushed function: ShowResultsButton
        function Results(app, event)
            Simulation = evalin('base', 'Simulation');
            
            if app.GeographicTrackMapCheckBox.Value == 1
                figure('Name','Track Map','NumberTitle','off')
                    zoom = app.ZoomEditField.Value/100; %  degrees2dms("grados")
                    geoplot(Simulation.Lat_Lon(:,1),Simulation.Lat_Lon(:,2),'-','LineWidth',1)
                    geolimits([min(Simulation.Lat_Lon(:,1))-zoom max(Simulation.Lat_Lon(:,1))+zoom],[min(Simulation.Lat_Lon(:,2))-zoom max(Simulation.Lat_Lon(:,2))+zoom])
                    geobasemap(app.MapTypeDropDown.Value) % streets % streets-light % satellite
                    hold on
                    geoplot(Simulation.Lat_Lon(1,1),Simulation.Lat_Lon(1,2),'^r')
                    geoplot(Simulation.Lat_Lon(length(Simulation.Lat_Lon(:,1)),1),Simulation.Lat_Lon(length(Simulation.Lat_Lon(:,2)),2),'vr')
                    clear zoom;
            end
            
                time_space = 1/evalin('base', 'time_step')*3;
                n = 1;
                n2 = 1;
                Simulation.Position_AUX = zeros(2,3);
                Simulation.Global_Velocity_AUX = zeros(2,3);
                Simulation.Global_Forces_AUX = zeros(2,3);
                while n < length(Simulation.Global_Velocity)
                    Simulation.Position_AUX(n2,:) = Simulation.Position(n,:);
                    Simulation.Global_Velocity_AUX(n2,:) = Simulation.Global_Velocity(n,:);
                    Simulation.Global_Forces_AUX(n2,:) = Simulation.Global_Forces(n,:);
                    n = n + time_space;
                    n2 = n2 + 1;
                end
                clear n n2;
            
            if app.DPositionCheckBox.Value == 1
                figure('Name','3D Position','NumberTitle','off')
                    plot3(Simulation.Position(:,1),Simulation.Position(:,2),Simulation.Position(:,3)*1000,'b', 'DisplayName','X Y Z Position' )
                    grid on
                    xlabel({'X [Km]'});
                    ylabel({'Y [Km]'});
                    zlabel({'Z [m]'});
                    legend('show')
            end
            
            if app.HeightVsCheckBox.Value == 1
                switch str2num(app.VsDropDown_2.Value)
                    case 1
                        figure('Name','Height Vs Time','NumberTitle','off')
                            plot(Simulation.tout,Simulation.Position(:,3)*1000,'b', 'DisplayName','Height')
                            grid on
                            xlabel({'Time [sec]'});
                            ylabel({'Height [m]'});
                            legend('show')
                    case 2
                        figure('Name','Height Vs Distance','NumberTitle','off')
                            plot(Simulation.Distance,Simulation.Position(:,3)*1000,'b', 'DisplayName','Height')
                            grid on
                            xlabel({'Distance [Km]'});
                            ylabel({'Height [m]'});
                            legend('show')
                end
            end
            
            if app.FuelVsCheckBox.Value == 1
                switch str2num(app.VsDropDown.Value)
                    case 1
                        figure('Name','Fuel Vs Time','NumberTitle','off')
                            plot(Simulation.tout,Simulation.Fuel,'b', 'DisplayName','Fuel')
                            grid on
                            xlabel({'Time [sec]'});
                            ylabel({'Fuel [l]'});
                            legend('show')
                    case 2
                        figure('Name','Fuel Vs Distance','NumberTitle','off')
                            plot(Simulation.Distance,Simulation.Fuel,'b', 'DisplayName','Fuel')
                            grid on
                            xlabel({'Distance [Km]'});
                            ylabel({'Fuel [l]'});
                            legend('show')
                end
                
            end

        end

        % Button pushed function: ExportResultsButton
        function ExportResults(app, event)
            Simulation = evalin('base', 'Simulation');
            
            if app.DateIndexCheckBox.Value == 1
                clock_aux = clock;
                FileName = [app.FilenameEditField.Value, '_', char(string(clock_aux(1))+string(clock_aux(2))+string(clock_aux(3))+string(clock_aux(4))+string(clock_aux(5)))];
            else    
                FileName = [app.FilenameEditField.Value];
            end
            clear clock_aux
            
            cd(app.CurrentFolder + "\Results")
            switch str2num(app.ExportType.Value)
                case 1
                    save([FileName, '.mat'],'Simulation')
                case 2
                    Simulation2 = struct(Simulation); Simulation3 = Simulation2.Data;
                    writetable(struct2table(Simulation3), [FileName, '.xlsx'])
                    clear Simulation2 Simulation3
                    winopen([FileName, '.xlsx'])
            end
            cd(app.CurrentFolder)


        end

        % Menu selected function: ImportExistingDataMATMenu
        function ImportResultsMAT(app, event)
            cd(app.CurrentFolder + "\Results")
            FileName = uigetfile('*.mat');
            aux = load(FileName)
            assignin('base', 'Simulation',aux.Simulation)
            clear aux
            cd(app.CurrentFolder)
        end

        % Menu selected function: AppLogoMenu
        function LogoShow(app, event)
            cd(app.CurrentFolder + "\Misc")
            I = imread('Simulator Logo 1.png');
            
            figure('Name','App Logo','NumberTitle','off','MenuBar','none','ToolBar','none','Color','w')
            imshow(I)
            cd(app.CurrentFolder)
        end

        % Close request function: UIFigure
        function CloseFcn(app, event)
            cd(app.CurrentFolder)
            if exist(app.CurrentFolder + "\slprj")
                rmdir(app.CurrentFolder + "\slprj", 's')
            end
            delete *.slxc
            
            delete(app)
        end

        % Button pushed function: AddRowButton
        function AddRow_Table(app, event)
            [m,n] = size(app.UITable.Data);
            app.TableData = app.UITable.Data;
            d = cell(m+1,n);
            d(1:m,1:n) = app.TableData;
            app.UITable.Data = d;
        end

        % Button pushed function: DeleteRowButton
        function DeleteRow_Table(app, event)
            [m,n] = size(app.UITable.Data);
            app.TableData = app.UITable.Data;
            d = cell(m-1,n);
            d(1:m,1:n) = app.TableData;
            clear app.UITable.Data
            app.UITable.Data = d(1:m-1,1:n);
        end

        % Cell edit callback: UITable
        function UpdateMission(app, event)
            indices = event.Indices;
            newData = event.NewData;
            assignin('base','Mission_Data',app.UITable.Data);
        end

        % Button pushed function: RunMissionButton
        function RunMission(app, event)
            clear app.TableData 
            [m,n] = size(app.UITable.Data);
            app.TableData = zeros(m,n);
            
            for i = 1:m
                if string(char(app.UITable.Data(i,1))) == string('Take Off')
                    app.TableData(i,1) = 1;
                else if string(char(app.UITable.Data(i,1))) == string('Cruise')
                        app.TableData(i,1) = 2;
                    else if string(char(app.UITable.Data(i,1))) == string('Continue in Real')
                            app.TableData(i,1) = 0;
                        end
                    end
                end
            end
            clear i
            
            for i = 1:m
                for j = 2:n
                    if isempty(cell2mat(app.UITable.Data(i,j)))
                        app.TableData(i,j) = 0;
                    else
                        app.TableData(i,j) = cell2mat(app.UITable.Data(i,j));
                    end
                end
            end
            clear i j
            
            if m < 2
                aux = zeros(2,5);
                    aux(1,:) = app.TableData;
                    aux(2,1) = 9;
                clear app.TableData 
                app.TableData = aux;
            end
            clear i aux
            
            
            evalin('base', 'clear Mission_Data')
            assignin('base','Mission_Data',app.TableData);
            assignin('base','Mission',1);
            
            if app.MissionCheckBox.Value == 1
                
                if app.BackgroundExecutionButton.Value == 1
                    aux = evalin('base', 'who');
                    app.main_Menu = 7;
                    cd(app.CurrentFolder);
                    main_Menu = app.main_Menu;
                    run(app.CurrentFolder + "\main.m")
                    for i = 1:length(VarsNames)
                        assignin('caller',char(VarsNames(i)),eval(char(VarsNames(i))));
                        assignin('base',char(VarsNames(i)),evalin('caller',char(VarsNames(i))));
                    end
                    clear aux i
                end
                
                if app.ExecutiononModelButton.Value == 1
                    cd(app.CurrentFolder);
                
                    evalin('base', 'who');
                    
                    open_system('SimulatorModel.mdl')
                    
                    evalin('base', 'clc');
                end
                evalin('caller', 'clear all');
               end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseFcn, true);
            app.AppLogoMenu = uimenu(app.UIFigure);
            app.AppLogoMenu.MenuSelectedFcn = createCallbackFcn(app, @LogoShow, true);
            app.AppLogoMenu.Text = 'App Logo';
            app.AircraftMenu = uimenu(app.UIFigure);
            app.AircraftMenu.Text = 'Aircraft';
            app.LoadExistingDataMenu = uimenu(app.AircraftMenu);
            app.LoadExistingDataMenu.MenuSelectedFcn = createCallbackFcn(app, @AircraftData, true);
            app.LoadExistingDataMenu.Text = 'Load Existing Data';
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.Text = 'Simulation';
            app.ImportExistingDataMATMenu = uimenu(app.SimulationMenu);
            app.ImportExistingDataMATMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportResultsMAT, true);
            app.ImportExistingDataMATMenu.Text = 'Import Existing Data (.MAT)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AircraftDataTab = uitab(app.TabGroup);
            app.AircraftDataTab.Title = 'Aircraft Data';
            app.MassLabel = uilabel(app.AircraftDataTab);
            app.MassLabel.Text = 'Mass:';
            app.MassEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.MassEditField.ValueDisplayFormat = '%.0f Kg';
            app.MassEditField.Editable = 'off';
            app.AircraftEditFieldLabel = uilabel(app.AircraftDataTab);
            app.AircraftEditFieldLabel.Text = 'Aircraft:';
            app.AircraftEditField = uieditfield(app.AircraftDataTab, 'text');
            app.AircraftEditField.Editable = 'off';
            app.AircraftEditField.Value = 'Name';
            app.CargoMassEditFieldLabel = uilabel(app.AircraftDataTab);
            app.CargoMassEditFieldLabel.Text = 'Cargo Mass:';
            app.CargoMassEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.CargoMassEditField.ValueDisplayFormat = '%.0f Kg';
            app.CargoMassEditField.ValueChangedFcn = createCallbackFcn(app, @CargoMass, true);
            app.FuelEditFieldLabel = uilabel(app.AircraftDataTab);
            app.FuelEditFieldLabel.Text = 'Fuel:';
            app.FuelEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.FuelEditField.ValueDisplayFormat = '%.0f l';
            app.FuelEditField.ValueChangedFcn = createCallbackFcn(app, @Fuel, true);
            app.InertiasKgm2Label = uilabel(app.AircraftDataTab);
            app.InertiasKgm2Label.Text = 'Inertias [Kg*m2]';
            app.IxxEditFieldLabel = uilabel(app.AircraftDataTab);
            app.IxxEditFieldLabel.Text = 'Ixx:';
            app.IxxEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.IxxEditField.ValueDisplayFormat = '%.0f';
            app.IxxEditField.Editable = 'off';
            app.IyyEditFieldLabel = uilabel(app.AircraftDataTab);
            app.IyyEditFieldLabel.Text = 'Iyy:';
            app.IyyEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.IyyEditField.ValueDisplayFormat = '%.0f';
            app.IyyEditField.Editable = 'off';
            app.IzzEditFieldLabel = uilabel(app.AircraftDataTab);
            app.IzzEditFieldLabel.Text = 'Izz:';
            app.IzzEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.IzzEditField.ValueDisplayFormat = '%.0f';
            app.IzzEditField.Editable = 'off';
            app.IzxEditFieldLabel = uilabel(app.AircraftDataTab);
            app.IzxEditFieldLabel.Text = 'Izx:';
            app.IzxEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.IzxEditField.ValueDisplayFormat = '%.0f';
            app.IzxEditField.Editable = 'off';
            app.IyzEditFieldLabel = uilabel(app.AircraftDataTab);
            app.IyzEditFieldLabel.Text = 'Iyz:';
            app.IyzEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.IyzEditField.ValueDisplayFormat = '%.0f';
            app.IyzEditField.Editable = 'off';
            app.IxyEditFieldLabel = uilabel(app.AircraftDataTab);
            app.IxyEditFieldLabel.Text = 'Ixy:';
            app.IxyEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.IxyEditField.ValueDisplayFormat = '%.0f';
            app.IxyEditField.Editable = 'off';
            app.SEditFieldLabel = uilabel(app.AircraftDataTab);
            app.SEditFieldLabel.Text = 'S:';
            app.SEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.SEditField.ValueDisplayFormat = '%.0f m2';
            app.SEditField.Editable = 'off';
            app.cEditFieldLabel = uilabel(app.AircraftDataTab);
            app.cEditFieldLabel.Text = 'c:';
            app.cEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.cEditField.ValueDisplayFormat = '%.0f m';
            app.cEditField.Editable = 'off';
            app.bEditFieldLabel = uilabel(app.AircraftDataTab);
            app.bEditFieldLabel.Text = 'b:';
            app.bEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.bEditField.ValueDisplayFormat = '%.0f m';
            app.bEditField.Editable = 'off';
            app.ShEditFieldLabel = uilabel(app.AircraftDataTab);
            app.ShEditFieldLabel.Text = 'Sh:';
            app.ShEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.ShEditField.ValueDisplayFormat = '%.0f m2';
            app.ShEditField.Editable = 'off';
            app.SvEditFieldLabel = uilabel(app.AircraftDataTab);
            app.SvEditFieldLabel.Text = 'Sv:';
            app.SvEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.SvEditField.ValueDisplayFormat = '%.0f m2';
            app.SvEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.AircraftDataTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'α')
            ylabel(app.UIAxes, 'Coef')
            app.CLButton = uibutton(app.AircraftDataTab, 'push');
            app.CLButton.ButtonPushedFcn = createCallbackFcn(app, @CL_Plot, true);
            app.CLButton.Text = 'CL';
            app.CoefficentsPlotLabel = uilabel(app.AircraftDataTab);
            app.CoefficentsPlotLabel.Text = 'Coefficents Plot';
            app.CD0Button = uibutton(app.AircraftDataTab, 'push');
            app.CD0Button.ButtonPushedFcn = createCallbackFcn(app, @CD0_Plot, true);
            app.CD0Button.Text = 'CD0';
            app.CD_Button = uibutton(app.AircraftDataTab, 'push');
            app.CD_Button.ButtonPushedFcn = createCallbackFcn(app, @CDbeta_Plot, true);
            app.CD_Button.Text = 'CD_β';
            app.CD_MachButton = uibutton(app.AircraftDataTab, 'push');
            app.CD_MachButton.ButtonPushedFcn = createCallbackFcn(app, @CDmach_Plot, true);
            app.CD_MachButton.Text = 'CD_Mach';
            app.Cm_eButton_2 = uibutton(app.AircraftDataTab, 'push');
            app.Cm_eButton_2.ButtonPushedFcn = createCallbackFcn(app, @Cmde_Plot, true);
            app.Cm_eButton_2.Text = 'Cm_δe';
            app.Cl_aButton = uibutton(app.AircraftDataTab, 'push');
            app.Cl_aButton.ButtonPushedFcn = createCallbackFcn(app, @Clda_Plot, true);
            app.Cl_aButton.Text = 'Cl_δa';
            app.EngineEditFieldLabel = uilabel(app.AircraftDataTab);
            app.EngineEditFieldLabel.Text = 'Engine:';
            app.EngineEditField = uieditfield(app.AircraftDataTab, 'text');
            app.EngineEditField.Editable = 'off';
            app.EngineEditField.Value = 'Model';
            app.EngineNumbEditField = uieditfield(app.AircraftDataTab, 'numeric');
            app.EngineNumbEditField.ValueDisplayFormat = 'x %.0f';
            app.EngineNumbEditField.Editable = 'off';
            app.kCLgeButton = uibutton(app.AircraftDataTab, 'push');
            app.kCLgeButton.ButtonPushedFcn = createCallbackFcn(app, @kCLge_Plot, true);
            app.kCLgeButton.Text = 'kCLge';
            app.kCDgeButton = uibutton(app.AircraftDataTab, 'push');
            app.kCDgeButton.ButtonPushedFcn = createCallbackFcn(app, @kCDge_Plot, true);
            app.kCDgeButton.Text = 'kCDge';
            app.UCALogo = uiimage(app.AircraftDataTab);
            app.OtherCoefficientsPanel = uipanel(app.AircraftDataTab);
            app.OtherCoefficientsPanel.Title = 'Other Coefficients';
            app.dCLflapLabel = uilabel(app.OtherCoefficientsPanel);
            app.dCLflapLabel.Text = 'dCLflap:';
            app.dCLflapEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.dCLflapEditField.ValueDisplayFormat = '%.3f';
            app.dCLflapEditField.Editable = 'off';
            app.CL_eLabel = uilabel(app.OtherCoefficientsPanel);
            app.CL_eLabel.Text = 'CL_δe:';
            app.CL_eEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CL_eEditField.ValueDisplayFormat = '%.3f';
            app.CL_eEditField.Editable = 'off';
            app.CDiEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CDiEditFieldLabel.Text = 'CDi:';
            app.CDiEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CDiEditField.ValueDisplayFormat = '%.3f';
            app.CDiEditField.Editable = 'off';
            app.CDflapEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CDflapEditFieldLabel.Text = 'CDflap:';
            app.CDflapEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CDflapEditField.ValueDisplayFormat = '%.3f';
            app.CDflapEditField.Editable = 'off';
            app.CDgearEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CDgearEditFieldLabel.Text = 'CDgear:';
            app.CDgearEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CDgearEditField.ValueDisplayFormat = '%.3f';
            app.CDgearEditField.Editable = 'off';
            app.CDsbEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CDsbEditFieldLabel.Text = 'CDsb:';
            app.CDsbEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CDsbEditField.ValueDisplayFormat = '%.3f';
            app.CDsbEditField.Editable = 'off';
            app.CDspEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CDspEditFieldLabel.Text = 'CDsp:';
            app.CDspEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CDspEditField.ValueDisplayFormat = '%.3f';
            app.CDspEditField.Editable = 'off';
            app.CDeLabel = uilabel(app.OtherCoefficientsPanel);
            app.CDeLabel.Text = 'CDδe:';
            app.CDeEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CDeEditField.ValueDisplayFormat = '%.3f';
            app.CDeEditField.Editable = 'off';
            app.CYLabel = uilabel(app.OtherCoefficientsPanel);
            app.CYLabel.Text = 'CYβ:';
            app.CYEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CYEditField.ValueDisplayFormat = '%.3f';
            app.CYEditField.Editable = 'off';
            app.Cm0EditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.Cm0EditFieldLabel.Text = 'Cm0:';
            app.Cm0EditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.Cm0EditField.ValueDisplayFormat = '%.3f';
            app.Cm0EditField.Editable = 'off';
            app.CmLabel = uilabel(app.OtherCoefficientsPanel);
            app.CmLabel.Text = 'Cmα:';
            app.CmEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CmEditField.ValueDisplayFormat = '%.3f';
            app.CmEditField.Editable = 'off';
            app.CmLabel_2 = uilabel(app.OtherCoefficientsPanel);
            app.CmLabel_2.Text = 'Cmα'':';
            app.CmEditField_2 = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CmEditField_2.ValueDisplayFormat = '%.3f';
            app.CmEditField_2.Editable = 'off';
            app.CmqEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CmqEditFieldLabel.Text = 'Cmq:';
            app.CmqEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CmqEditField.ValueDisplayFormat = '%.3f';
            app.CmqEditField.Editable = 'off';
            app.CnLabel = uilabel(app.OtherCoefficientsPanel);
            app.CnLabel.Text = 'Cnβ:';
            app.CnEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CnEditField.ValueDisplayFormat = '%.3f';
            app.CnEditField.Editable = 'off';
            app.CndrEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CndrEditFieldLabel.Text = 'Cndr:';
            app.CndrEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CndrEditField.ValueDisplayFormat = '%.3f';
            app.CndrEditField.Editable = 'off';
            app.CnrEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CnrEditFieldLabel.Text = 'Cnr:';
            app.CnrEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CnrEditField.ValueDisplayFormat = '%.3f';
            app.CnrEditField.Editable = 'off';
            app.ClrEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.ClrEditFieldLabel.Text = 'Clr:';
            app.ClrEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.ClrEditField.ValueDisplayFormat = '%.3f';
            app.ClrEditField.Editable = 'off';
            app.ClpEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.ClpEditFieldLabel.Text = 'Clp:';
            app.ClpEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.ClpEditField.ValueDisplayFormat = '%.3f';
            app.ClpEditField.Editable = 'off';
            app.ClEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.ClEditFieldLabel.Text = 'Clβ:';
            app.ClEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.ClEditField.ValueDisplayFormat = '%.3f';
            app.ClEditField.Editable = 'off';
            app.CldrEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.CldrEditFieldLabel.Text = 'Cldr:';
            app.CldrEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.CldrEditField.ValueDisplayFormat = '%.3f';
            app.CldrEditField.Editable = 'off';
            app.dLabel = uilabel(app.OtherCoefficientsPanel);
            app.dLabel.Text = 'µd:';
            app.dEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.dEditField.ValueDisplayFormat = '%.3f';
            app.dEditField.Editable = 'off';
            app.kCLsbEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.kCLsbEditFieldLabel.Text = 'kCLsb:';
            app.kCLsbEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.kCLsbEditField.ValueDisplayFormat = '%.3f';
            app.kCLsbEditField.Editable = 'off';
            app.kCLspEditFieldLabel = uilabel(app.OtherCoefficientsPanel);
            app.kCLspEditFieldLabel.Text = 'kCLsp:';
            app.kCLspEditField = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.kCLspEditField.ValueDisplayFormat = '%.3f';
            app.kCLspEditField.Editable = 'off';
            app.cLabel = uilabel(app.OtherCoefficientsPanel);
            app.cLabel.Text = 'µc:';
            app.cEditField_2 = uieditfield(app.OtherCoefficientsPanel, 'numeric');
            app.cEditField_2.ValueDisplayFormat = '%.3f';
            app.cEditField_2.Editable = 'off';
            app.RealTimeTab = uitab(app.TabGroup);
            app.RealTimeTab.Title = 'Real-Time';
            app.JoyStickConecctionLampLabel = uilabel(app.RealTimeTab);
            app.JoyStickConecctionLampLabel.Text = 'JoyStick Conecction';
            app.JoyStickConecctionLamp = uilamp(app.RealTimeTab);
            app.OpenSimulinkModelButton = uibutton(app.RealTimeTab, 'push');
            app.OpenSimulinkModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModel, true);
            app.OpenSimulinkModelButton.Text = 'Open Simulink Model';
            app.WarningLabel = uilabel(app.RealTimeTab);
            app.WarningLabel.Text = 'Warning:';
            app.DonotopenmodelifjoystickconnectionisredLabel = uilabel(app.RealTimeTab);
            app.DonotopenmodelifjoystickconnectionisredLabel.Text = 'Do not open model if joystick connection is red.';
            app.UCALogo_2 = uiimage(app.RealTimeTab);
            app.InitialConditionsPanel = uipanel(app.RealTimeTab);
            app.InitialConditionsPanel.Title = 'Initial Conditions';
            app.xEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.xEditField.ValueDisplayFormat = '%11.4g m';
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @x_ini, true);
            app.yEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.yEditField.ValueDisplayFormat = '%11.4g m';
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @y_ini, true);
            app.zEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.zEditField.ValueDisplayFormat = '%11.4g m';
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @z_ini, true);
            app.uEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.uEditFieldLabel.Text = 'u';
            app.uEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.uEditField.ValueDisplayFormat = '%11.4g m/s';
            app.uEditField.ValueChangedFcn = createCallbackFcn(app, @u_ini, true);
            app.vEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.vEditField.ValueDisplayFormat = '%11.4g m/s';
            app.vEditField.ValueChangedFcn = createCallbackFcn(app, @v_ini, true);
            app.wEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.wEditField.ValueDisplayFormat = '%11.4g m/s';
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @w_ini, true);
            app.pEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.pEditField.ValueDisplayFormat = '%11.4g º/s';
            app.pEditField.ValueChangedFcn = createCallbackFcn(app, @p_ini, true);
            app.qEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.qEditField.ValueDisplayFormat = '%11.4g º/s';
            app.qEditField.ValueChangedFcn = createCallbackFcn(app, @q_ini, true);
            app.rEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.rEditField.ValueDisplayFormat = '%11.4g º/s';
            app.rEditField.ValueChangedFcn = createCallbackFcn(app, @r_ini, true);
            app.Label = uilabel(app.InitialConditionsPanel);
            app.Label.Text = 'Ψ';
            app.PsiEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.PsiEditField.ValueDisplayFormat = '%11.4g º';
            app.PsiEditField.ValueChangedFcn = createCallbackFcn(app, @psi_ini, true);
            app.EditField_2Label = uilabel(app.InitialConditionsPanel);
            app.EditField_2Label.Text = 'θ';
            app.ThetaEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.ThetaEditField.ValueDisplayFormat = '%11.4g º';
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @theta_ini, true);
            app.EditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.EditFieldLabel.Text = 'φ';
            app.PhiEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.PhiEditField.ValueDisplayFormat = '%11.4g º';
            app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @phi_ini, true);
            app.LatitudeEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.LatitudeEditField.ValueDisplayFormat = '%.2f º';
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @Lat, true);
            app.LongitudeEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.LongitudeEditField.ValueDisplayFormat = '%.2f º';
            app.ElevationEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.ElevationEditField.ValueDisplayFormat = '%.0f m';
            app.ActuatorTrimPanel = uipanel(app.RealTimeTab);
            app.ActuatorTrimPanel.Title = 'Actuator Trim';
            app.AileronEditFieldLabel = uilabel(app.ActuatorTrimPanel);
            app.AileronEditFieldLabel.Text = 'Aileron';
            app.AileronEditField = uieditfield(app.ActuatorTrimPanel, 'numeric');
            app.AileronEditField.ValueDisplayFormat = '%.1f %%';
            app.AileronEditField.ValueChangedFcn = createCallbackFcn(app, @AileronTrim, true);
            app.RudderEditFieldLabel = uilabel(app.ActuatorTrimPanel);
            app.RudderEditFieldLabel.Text = 'Rudder';
            app.RudderEditField = uieditfield(app.ActuatorTrimPanel, 'numeric');
            app.RudderEditField.ValueDisplayFormat = '%.1f %%';
            app.RudderEditField.ValueChangedFcn = createCallbackFcn(app, @RudderTrim, true);
            app.ElevatorEditFieldLabel = uilabel(app.ActuatorTrimPanel);
            app.ElevatorEditFieldLabel.Text = 'Elevator';
            app.ElevatorEditField = uieditfield(app.ActuatorTrimPanel, 'numeric');
            app.ElevatorEditField.ValueDisplayFormat = '%.1f %%';
            app.ElevatorEditField.ValueChangedFcn = createCallbackFcn(app, @ElevatorTrim, true);
            app.EngineEditField_2Label = uilabel(app.ActuatorTrimPanel);
            app.EngineEditField_2Label.Text = 'Engine';
            app.EngineEditField_2 = uieditfield(app.ActuatorTrimPanel, 'numeric');
            app.EngineEditField_2.ValueDisplayFormat = '%.1f %%';
            app.EngineEditField_2.ValueChangedFcn = createCallbackFcn(app, @EngineTrim, true);
            app.DateDatePickerLabel = uilabel(app.RealTimeTab);
            app.DateDatePickerLabel.Text = 'Date:';
            app.DateDatePicker = uidatepicker(app.RealTimeTab);
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DayDate, true);
            app.DayTimeLabel = uilabel(app.RealTimeTab);
            app.DayTimeLabel.Text = 'Day Time:';
            app.DayTimeSpinner = uispinner(app.RealTimeTab);
            app.DayTimeSpinner.Step = 0.25;
            app.DayTimeSpinner.Limits = [0 24];
            app.DayTimeSpinner.ValueDisplayFormat = '%11.4g h';
            app.DayTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @DayTime, true);
            app.TimeStepLabel = uilabel(app.RealTimeTab);
            app.TimeStepLabel.Text = 'Time Step:';
            app.TimeStepSpinner = uispinner(app.RealTimeTab);
            app.TimeStepSpinner.Step = 0.2;
            app.TimeStepSpinner.Limits = [0.2 Inf];
            app.TimeStepSpinner.ValueDisplayFormat = '%11.4g seconds';
            app.TimeStepSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeStep, true);
            app.TimeStepSpinner.Value = 0.2;
            app.AdjustTrimConditionsButton = uibutton(app.RealTimeTab, 'push');
            app.AdjustTrimConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @Trim, true);
            app.AdjustTrimConditionsButton.Text = 'Adjust Trim Conditions';
            app.ApIndexLabel = uilabel(app.RealTimeTab);
            app.ApIndexLabel.Text = 'Ap Index:';
            app.ApDropDown = uidropdown(app.RealTimeTab);
            app.ApDropDown.Items = {'0', '2', '3', '4', '5', '6', '7', '9', '12', '15', '18', '22', '27', '32', '39', '48', '56', '67', '80', '94', '111', '132', '154', '179', '207', '236', '300', '400'};
            app.ApDropDown.ItemsData = {'0', '2', '3', '4', '5', '6', '7', '9', '12', '15', '18', '22', '27', '32', '39', '48', '56', '67', '80', '94', '111', '132', '154', '179', '207', '236', '300', '400'};
            app.ApDropDown.ValueChangedFcn = createCallbackFcn(app, @ApSelect, true);
            app.ApDropDown.Value = '0';
            app.MissionTab = uitab(app.TabGroup);
            app.MissionTab.Title = 'Mission';
            app.UITable = uitable(app.MissionTab);
            app.UITable.ColumnName = {'Operations'; 'Data 1'; 'Data 2'; 'Data 3'; 'Data 4'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UpdateMission, true);
            app.AddRowButton = uibutton(app.MissionTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRow_Table, true);
            app.AddRowButton.Text = 'Add Row';
            app.DeleteRowButton = uibutton(app.MissionTab, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRow_Table, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.RunMissionButton = uibutton(app.MissionTab, 'push');
            app.RunMissionButton.ButtonPushedFcn = createCallbackFcn(app, @RunMission, true);
            app.RunMissionButton.Text = 'Run Mission';
            app.WarningLabel_2 = uilabel(app.MissionTab);
            app.WarningLabel_2.Text = 'Warning:';
            app.EachoperationhavedifferentpropertiesdonotconfusethemLabel = uilabel(app.MissionTab);
            app.EachoperationhavedifferentpropertiesdonotconfusethemLabel.Text = 'Each operation have different properties, do not confuse them.';
            app.TakeOffLabel = uilabel(app.MissionTab);
            app.TakeOffLabel.Text = '> Take Off: ';
            app.NoDataneededLabel = uilabel(app.MissionTab);
            app.NoDataneededLabel.Text = 'No Data needed';
            app.CruiseLabel = uilabel(app.MissionTab);
            app.CruiseLabel.Text = '> Cruise: ';
            app.Data1HeightinftData2VelocityinknotsData3DistanceinKmLabel = uilabel(app.MissionTab);
            app.Data1HeightinftData2VelocityinknotsData3DistanceinKmLabel.Text = 'Data 1: Height in ft  |  Data 2: Velocity in knots  |  Data 3: Distance in Km  |  Data 4: Direction in deg';
            app.DesignedMissionExecutionButtonGroup = uibuttongroup(app.MissionTab);
            app.DesignedMissionExecutionButtonGroup.Title = 'Designed Mission Execution';
            app.BackgroundExecutionButton = uiradiobutton(app.DesignedMissionExecutionButtonGroup);
            app.BackgroundExecutionButton.Text = 'Background Execution';
            app.BackgroundExecutionButton.Value = true;
            app.ExecutiononModelButton = uiradiobutton(app.DesignedMissionExecutionButtonGroup);
            app.ExecutiononModelButton.Text = 'Execution on Model';
            app.ContinueinRealLabel = uilabel(app.MissionTab);
            app.ContinueinRealLabel.Text = '> Continue in Real: ';
            app.NoDataneededDonotchoosethisoptioninBackgroundExecutionLabel = uilabel(app.MissionTab);
            app.NoDataneededDonotchoosethisoptioninBackgroundExecutionLabel.Text = 'No Data needed. (Do not choose this option in ''Background Execution'')';
            app.MissionCheckBox = uicheckbox(app.MissionTab);
            app.MissionCheckBox.Text = 'Model-Execution';
            app.MissionCheckBox.Value = true;
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ShowResultsButton = uibutton(app.ResultsTab, 'push');
            app.ShowResultsButton.ButtonPushedFcn = createCallbackFcn(app, @Results, true);
            app.ShowResultsButton.Text = 'Show Results';
            app.MapTypeDropDownLabel = uilabel(app.ResultsTab);
            app.MapTypeDropDownLabel.Text = 'Map Type:';
            app.MapTypeDropDown = uidropdown(app.ResultsTab);
            app.MapTypeDropDown.Items = {'Streets ', 'Streets-light ', 'Satellite'};
            app.MapTypeDropDown.ItemsData = {'streets', 'streets-light', 'satellite'};
            app.MapTypeDropDown.Value = 'streets';
            app.ZoomEditFieldLabel = uilabel(app.ResultsTab);
            app.ZoomEditFieldLabel.Text = 'Zoom:';
            app.ZoomEditField = uieditfield(app.ResultsTab, 'numeric');
            app.ZoomEditField.ValueDisplayFormat = '%.0f %%';
            app.ZoomEditField.Value = 2;
            app.GeographicTrackMapCheckBox = uicheckbox(app.ResultsTab);
            app.GeographicTrackMapCheckBox.Text = '  Geographic Track Map';
            app.GeographicTrackMapCheckBox.Value = true;
            app.DPositionCheckBox = uicheckbox(app.ResultsTab);
            app.DPositionCheckBox.Text = '  3D Position';
            app.DPositionCheckBox.Value = true;
            app.HeightVsCheckBox = uicheckbox(app.ResultsTab);
            app.HeightVsCheckBox.Text = '  Height Vs ...';
            app.HeightVsCheckBox.Value = true;
            app.FuelVsCheckBox = uicheckbox(app.ResultsTab);
            app.FuelVsCheckBox.Text = '  Fuel Vs ...';
            app.FuelVsCheckBox.Value = true;
            app.VsDropDown = uidropdown(app.ResultsTab);
            app.VsDropDown.Items = {'Time', 'Distance'};
            app.VsDropDown.ItemsData = {'1', '2', ''};
            app.VsDropDown.Value = '1';
            app.VsDropDown_2 = uidropdown(app.ResultsTab);
            app.VsDropDown_2.Items = {'Time', 'Distance'};
            app.VsDropDown_2.ItemsData = {'1', '2', ''};
            app.VsDropDown_2.Value = '1';
            app.ExportResultsButton = uibutton(app.ResultsTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResults, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.ExportType = uidropdown(app.ResultsTab);
            app.ExportType.Items = {'.MAT (MatLab)', '.XLSX (Excel)'};
            app.ExportType.ItemsData = {'1', '2', ''};
            app.ExportType.Value = '1';
            app.toLabel = uilabel(app.ResultsTab);
            app.toLabel.Text = 'to';
            app.FilenameEditFieldLabel = uilabel(app.ResultsTab);
            app.FilenameEditFieldLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.ResultsTab, 'text');
            app.FilenameEditField.Value = 'Simulation Results';
            app.DateIndexCheckBox = uicheckbox(app.ResultsTab);
            app.DateIndexCheckBox.Text = 'Date Index';
            app.DateIndexCheckBox.Value = true;
            app.Copyright2022ngelDanielVelzquezRodrguezLabel = uilabel(app.ResultsTab);
            app.Copyright2022ngelDanielVelzquezRodrguezLabel.Text = 'Copyright © 2022  Ángel Daniel Velázquez Rodríguez';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end